classdef EFECTOS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Lamp_2                    matlab.ui.control.Lamp
        Lamp                      matlab.ui.control.Lamp
        FLANGINGSlider_2          matlab.ui.control.Slider
        FLANGINGSlider_2Label     matlab.ui.control.Label
        TREMOLOSlider_2           matlab.ui.control.Slider
        TREMOLOSlider_2Label      matlab.ui.control.Label
        EFECTOSButtonGroup        matlab.ui.container.ButtonGroup
        TREMOLOButton             matlab.ui.control.RadioButton
        PANINGButton              matlab.ui.control.RadioButton
        FLANGINGButton            matlab.ui.control.RadioButton
        REVERBERACIONButton       matlab.ui.control.RadioButton
        ECOButton                 matlab.ui.control.RadioButton
        NINGUNOButton             matlab.ui.control.RadioButton
        PANINGSlider              matlab.ui.control.Slider
        PANINGSliderLabel         matlab.ui.control.Label
        REVERBERACIONSlider       matlab.ui.control.Slider
        REVERBERACIONSliderLabel  matlab.ui.control.Label
        ECOSlider                 matlab.ui.control.Slider
        ECOSliderLabel            matlab.ui.control.Label
        PARARButton               matlab.ui.control.Button
        PAUSARButton              matlab.ui.control.Button
        REPRODUCIRButton          matlab.ui.control.Button
        GRABARSONIDOButton        matlab.ui.control.Button
        EFECTOSDESONIDOLabel      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRABARSONIDOButton
        function GRABARSONIDOButtonPushed(app, event)
Duracion = 10;
Fs = 44100;
Entrada = audiorecorder(Fs,16,1);
msgbox('Grabando','titulo');
recordblocking(Entrada,Duracion);
msgbox('FInalizo','titulo');
x=getaudiodata(Entrada,'int16');
audiowrite('sonido.wav',x,Fs);
        end

        % Selection changed function: EFECTOSButtonGroup
        function EFECTOSButtonGroupSelectionChanged(app, event)
            selectedButton = app.EFECTOSButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EFECTOSDESONIDOLabel = uilabel(app.UIFigure);
            app.EFECTOSDESONIDOLabel.Text = 'EFECTOS DE SONIDO';
            app.GRABARSONIDOButton = uibutton(app.UIFigure, 'push');
            app.GRABARSONIDOButton.ButtonPushedFcn = createCallbackFcn(app, @GRABARSONIDOButtonPushed, true);
            app.GRABARSONIDOButton.Text = 'GRABAR SONIDO';
            app.REPRODUCIRButton = uibutton(app.UIFigure, 'push');
            app.REPRODUCIRButton.Text = 'REPRODUCIR';
            app.PAUSARButton = uibutton(app.UIFigure, 'push');
            app.PAUSARButton.Text = 'PAUSAR';
            app.PARARButton = uibutton(app.UIFigure, 'push');
            app.PARARButton.Text = 'PARAR';
            app.ECOSliderLabel = uilabel(app.UIFigure);
            app.ECOSliderLabel.Text = 'ECO';
            app.ECOSlider = uislider(app.UIFigure);
            app.REVERBERACIONSliderLabel = uilabel(app.UIFigure);
            app.REVERBERACIONSliderLabel.Text = 'REVERBERACION';
            app.REVERBERACIONSlider = uislider(app.UIFigure);
            app.PANINGSliderLabel = uilabel(app.UIFigure);
            app.PANINGSliderLabel.Text = 'PANING';
            app.PANINGSlider = uislider(app.UIFigure);
            app.EFECTOSButtonGroup = uibuttongroup(app.UIFigure);
            app.EFECTOSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EFECTOSButtonGroupSelectionChanged, true);
            app.EFECTOSButtonGroup.Title = '         EFECTOS';
            app.NINGUNOButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.NINGUNOButton.Text = 'NINGUNO';
            app.NINGUNOButton.Value = true;
            app.ECOButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.ECOButton.Text = 'ECO';
            app.REVERBERACIONButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.REVERBERACIONButton.Text = 'REVERBERACION';
            app.FLANGINGButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.FLANGINGButton.Text = 'FLANGING';
            app.PANINGButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.PANINGButton.Text = 'PANING';
            app.TREMOLOButton = uiradiobutton(app.EFECTOSButtonGroup);
            app.TREMOLOButton.Text = 'TREMOLO';
            app.TREMOLOSlider_2Label = uilabel(app.UIFigure);
            app.TREMOLOSlider_2Label.Text = 'TREMOLO';
            app.TREMOLOSlider_2 = uislider(app.UIFigure);
            app.FLANGINGSlider_2Label = uilabel(app.UIFigure);
            app.FLANGINGSlider_2Label.Text = 'FLANGING';
            app.FLANGINGSlider_2 = uislider(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EFECTOS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end